classdef GameView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        StepButton  matlab.ui.control.Button
        Option      matlab.ui.control.Button
        Zhangfei    matlab.ui.control.Button
        Caocao      matlab.ui.control.Button
        Zhaoyun     matlab.ui.control.Button
        Machao      matlab.ui.control.Button
        Guanyu      matlab.ui.control.Button
        Huangzhong  matlab.ui.control.Button
        soldier1    matlab.ui.control.Button
        soldier2    matlab.ui.control.Button
        soldier3    matlab.ui.control.Button
        soldier4    matlab.ui.control.Button
        solvebyAI   matlab.ui.control.Button
    end

    
    properties (Access = public)
        chessDB
        gameProcess
        startDB
        ov      % option view
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button down function: UIFigure
        function Move(app, event)
            clickPoint = app.UIFigure.CurrentPoint;
            app.chessDB.currentLocate = ...
                app.gameProcess.transMatrix(app.chessDB.position);
            app.chessDB.currentChess  = ...
                app.gameProcess.getCurrentChess(app.chessDB.id,app.chessDB.position);
            app.chessDB.nextPosition = ...
                app.gameProcess.move(clickPoint);
            app.gameProcess.judge();
        end

        % Button pushed function: Option
        function OptionButtonPushed(app, event)
            app.ov = optionView;
            app.ov.chessDB = app.chessDB;
            app.ov.gameView = app;
            app.ov.startDB = app.startDB;
        end

        % Button pushed function: Caocao
        function CaocaoButtonPushed(app, event)
            app.chessDB.id=1;
        end

        % Button pushed function: Zhangfei
        function ZhangfeiButtonPushed(app, event)
            app.chessDB.id=2;
        end

        % Button pushed function: Zhaoyun
        function ZhaoyunButtonPushed(app, event)
            app.chessDB.id=3;
        end

        % Button pushed function: Machao
        function MachaoButtonPushed(app, event)
            app.chessDB.id=4;
        end

        % Button pushed function: Guanyu
        function GuanyuButtonPushed(app, event)
            app.chessDB.id=6;
        end

        % Button pushed function: Huangzhong
        function HuangzhongButtonPushed(app, event)
            app.chessDB.id=5;
        end

        % Button pushed function: soldier1
        function soldier1ButtonPushed(app, event)
            app.chessDB.id=7;
        end

        % Button pushed function: soldier4
        function soldier2ButtonPushed(app, event)
            app.chessDB.id=8;
        end

        % Button pushed function: soldier2
        function soldier3ButtonPushed(app, event)
            app.chessDB.id=9;
        end

        % Button pushed function: soldier3
        function soldier4ButtonPushed(app, event)
            app.chessDB.id=10;
        end

        % Button pushed function: solvebyAI
        function solvebyAIButtonPushed(app, event)
            a = app.gameProcess.transMatrix(app.chessDB.position);
            fprintf(('{%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f,%2.0f}\n'),a(1,1),a(1,2),a(1,3),a(1,4), ...
                a(2,1),a(2,2),a(2,3),a(2,4),a(3,1),a(3,2),a(3,3),a(3,4),a(4,1),a(4,2),a(4,3),a(4,4), ...
                a(5,1),a(5,2),a(5,3),a(5,4));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '华容道';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @Move, true);
            app.StepButton = uibutton(app.UIFigure, 'push');
            app.StepButton.Text = '步数';
            app.Option = uibutton(app.UIFigure, 'push');
            app.Option.ButtonPushedFcn = createCallbackFcn(app, @OptionButtonPushed, true);
            app.Option.Text = '选项';
            app.Zhangfei = uibutton(app.UIFigure, 'push');
            app.Zhangfei.ButtonPushedFcn = createCallbackFcn(app, @ZhangfeiButtonPushed, true);
            app.Zhangfei.Icon = '张飞.jpg';
            app.Zhangfei.Text = '';
            app.Caocao = uibutton(app.UIFigure, 'push');
            app.Caocao.ButtonPushedFcn = createCallbackFcn(app, @CaocaoButtonPushed, true);
            app.Caocao.Icon = '曹操.jpg';
            app.Caocao.Text = '';
            app.Zhaoyun = uibutton(app.UIFigure, 'push');
            app.Zhaoyun.ButtonPushedFcn = createCallbackFcn(app, @ZhaoyunButtonPushed, true);
            app.Zhaoyun.Icon = '赵云.jpg';
            app.Zhaoyun.Text = '';
            app.Machao = uibutton(app.UIFigure, 'push');
            app.Machao.ButtonPushedFcn = createCallbackFcn(app, @MachaoButtonPushed, true);
            app.Machao.Icon = '马超.jpg';
            app.Machao.Text = '';
            app.Guanyu = uibutton(app.UIFigure, 'push');
            app.Guanyu.ButtonPushedFcn = createCallbackFcn(app, @GuanyuButtonPushed, true);
            app.Guanyu.Icon = '关羽.jpg';
            app.Guanyu.Text = '';
            app.Huangzhong = uibutton(app.UIFigure, 'push');
            app.Huangzhong.ButtonPushedFcn = createCallbackFcn(app, @HuangzhongButtonPushed, true);
            app.Huangzhong.Icon = '黄忠.jpg';
            app.Huangzhong.Text = '';
            app.soldier1 = uibutton(app.UIFigure, 'push');
            app.soldier1.ButtonPushedFcn = createCallbackFcn(app, @soldier1ButtonPushed, true);
            app.soldier1.Icon = '士兵.jpg';
            app.soldier1.Text = '';
            app.soldier2 = uibutton(app.UIFigure, 'push');
            app.soldier2.ButtonPushedFcn = createCallbackFcn(app, @soldier3ButtonPushed, true);
            app.soldier2.Icon = '士兵.jpg';
            app.soldier2.Text = '';
            app.soldier3 = uibutton(app.UIFigure, 'push');
            app.soldier3.ButtonPushedFcn = createCallbackFcn(app, @soldier4ButtonPushed, true);
            app.soldier3.Icon = '士兵.jpg';
            app.soldier3.Text = '';
            app.soldier4 = uibutton(app.UIFigure, 'push');
            app.soldier4.ButtonPushedFcn = createCallbackFcn(app, @soldier2ButtonPushed, true);
            app.soldier4.Icon = '士兵.jpg';
            app.soldier4.Text = '';
            app.solvebyAI = uibutton(app.UIFigure, 'push');
            app.solvebyAI.ButtonPushedFcn = createCallbackFcn(app, @solvebyAIButtonPushed, true);
            app.solvebyAI.Text = '验证';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GameView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end