classdef creatView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Caocao          matlab.ui.control.Button
        Button_1        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
        Button_5        matlab.ui.control.Button
        Button_6        matlab.ui.control.Button
        Button_7        matlab.ui.control.Button
        Button_8        matlab.ui.control.Button
        clickButton     matlab.ui.control.Button
        clickButton_2   matlab.ui.control.Button
        clickButton_3   matlab.ui.control.Button
        clickButton_4   matlab.ui.control.Button
        clickButton_5   matlab.ui.control.Button
        clickButton_6   matlab.ui.control.Button
        clickButton_7   matlab.ui.control.Button
        clickButton_8   matlab.ui.control.Button
        clickButton_9   matlab.ui.control.Button
        clickButton_10  matlab.ui.control.Button
        clickButton_11  matlab.ui.control.Button
        clickButton_12  matlab.ui.control.Button
        clickButton_13  matlab.ui.control.Button
        clickButton_14  matlab.ui.control.Button
        clickButton_15  matlab.ui.control.Button
        clickButton_16  matlab.ui.control.Button
    end

    
    properties (Access = public)
        diyView
        a
        b
        i
        move
        board = {0,1,1,0,0,1,1,0,0,0,0,0,0,0,0,0,0,0,0,0};
        
    end
    
    methods (Access = public)
        
        function func(app)
            position = app.diyView.ButtonHandle;
            if(app.move==1)
                if(position(3) ==90 && position(4)==90)
                    if(app.board{1}==0)
                        app.clickButton.Position = [5,405,90,90];
                    end
                    if(app.board{4}==0)
                        app.clickButton_2.Position = [305,405,90,90];
                    end
                    if(app.board{5}==0)
                        app.clickButton_3.Position = [5,305,90,90];
                    end
                    if(app.board{8}==0)
                        app.clickButton_4.Position = [305,305,90,90];
                    end
                    if(app.board{9}==0)
                        app.clickButton_5.Position = [5,205,90,90];
                    end
                    if(app.board{10}==0)
                        app.clickButton_6.Position = [105,205,90,90];
                    end
                    if(app.board{11}==0)
                        app.clickButton_7.Position = [205,205,90,90];
                    end
                    if(app.board{12}==0)
                        app.clickButton_8.Position = [305,205,90,90];
                    end
                    if(app.board{13}==0)
                        app.clickButton_9.Position = [5,105,90,90];
                    end
                    if(app.board{14}==0)
                        app.clickButton_10.Position = [105,105,90,90];
                    end
                    if(app.board{15}==0)
                        app.clickButton_11.Position = [205,105,90,90];
                    end
                    if(app.board{16}==0)
                        app.clickButton_12.Position = [305,105,90,90];
                    end
                    if(app.board{17}==0)
                        app.clickButton_13.Position = [5,5,90,90];
                    end
                    if(app.board{18}==0)
                        app.clickButton_14.Position = [105,5,90,90];
                    end
                    if(app.board{19}==0)
                        app.clickButton_15.Position = [205,5,90,90];
                    end
                    if(app.board{20}==0)
                        app.clickButton_16.Position = [305,5,90,90];
                    end
                elseif position(3) ==90 && position(4)==190
                    if(app.board{1}==0&&app.board{5}==0)
                        app.clickButton.Position = [5,305,90,90];
                    end
                    if(app.board{4}==0&&app.board{8}==0)
                        app.clickButton_2.Position = [305,305,90,90];
                    end
                    if(app.board{5}==0&&app.board{9}==0)
                        app.clickButton_3.Position = [5,205,90,90];
                    end
                    if(app.board{8}==0&&app.board{12}==0)
                        app.clickButton_4.Position = [305,205,90,90];
                    end
                    if(app.board{9}==0&&app.board{13}==0)
                        app.clickButton_5.Position = [5,105,90,90];
                    end
                    if(app.board{10}==0&&app.board{14}==0)
                        app.clickButton_6.Position = [105,105,90,90];
                    end
                    if(app.board{11}==0&&app.board{15}==0)
                        app.clickButton_7.Position = [205,105,90,90];
                    end
                    if(app.board{12}==0&&app.board{13}==0)
                        app.clickButton_8.Position = [305,105,90,90];
                    end
                    if(app.board{13}==0&&app.board{17}==0)
                        app.clickButton_9.Position = [5,5,90,90];
                    end
                    if(app.board{14}==0&&app.board{18}==0)
                        app.clickButton_10.Position = [105,5,90,90];
                    end
                    if(app.board{15}==0&&app.board{19}==0)
                        app.clickButton_11.Position = [205,5,90,90];
                    end
                    if(app.board{19}==0&&app.board{20}==0)
                        app.clickButton_12.Position = [305,5,90,90];
                    end
                elseif position(3) == 190 && position(4) == 90
                    if(app.board{9}==0&&app.board{10}==0)
                        app.clickButton.Position = [5,205,90,90];
                    end
                    if(app.board{10}==0&&app.board{11}==0)
                        app.clickButton_2.Position = [105,205,90,90];
                    end
                    if(app.board{11}==0&&app.board{12}==0)
                        app.clickButton_3.Position = [205,205,90,90];
                    end
                    if(app.board{13}==0&&app.board{14}==0)
                        app.clickButton_4.Position = [5,105,90,90];
                    end
                    if(app.board{14}==0&&app.board{15}==0)
                        app.clickButton_5.Position = [105,105,90,90];
                    end
                    if(app.board{15}==0&&app.board{16}==0)
                        app.clickButton_6.Position = [205,105,90,90];
                    end
                    if(app.board{17}==0&&app.board{18}==0)
                        app.clickButton_7.Position = [5,5,90,90];
                    end
                    if(app.board{18}==0&&app.board{19}==0)
                        app.clickButton_8.Position = [105,5,90,90];
                    end
                    if(app.board{19}==0&&app.board{20}==0)
                        app.clickButton_9.Position = [205,5,90,90];
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function emptyClick(app, event)
%             app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
%             position = app.diyView.ButtonHandle;
%             app.a(app.i).Icon = app.diyView.IconHandle;
%             clickPoint = app.UIFigure.CurrentPoint;
%             if(app.move==1)
%              if(clickPoint(1)>5&&clickPoint(1)<105)
%                 if(clickPoint(2)>5&&clickPoint(2)<105)
%                     position = [5,5,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>105&&clickPoint(2)<205)
%                     position = [5,105,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>205&&clickPoint(2)<305)
%                     position = [5,205,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>305&&clickPoint(2)<405)
%                     position = [5,305,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>405&&clickPoint(2)<505)
%                     position = [5,405,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 end
%             elseif (clickPoint(1)>105&&clickPoint(1)<205)
%                 if(clickPoint(2)>5&&clickPoint(2)<105)
%                     position = [105,5,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>105&&clickPoint(2)<205)
%                     position = [105,105,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>205&&clickPoint(2)<305)
%                     position = [105,205,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>305&&clickPoint(2)<405)
%                     position = [105,305,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>405&&clickPoint(2)<505)
%                     position = [105,405,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 end
%             elseif (clickPoint(1)>205&&clickPoint(1)<305)
%                 if(clickPoint(2)>5&&clickPoint(2)<105)
%                     position = [205,5,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>105&&clickPoint(2)<205)
%                     position = [205,105,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>205&&clickPoint(2)<305)
%                     position = [205,205,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>305&&clickPoint(2)<405)
%                     position = [205,305,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>405&&clickPoint(2)<505)
%                     position = [205,405,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 end
%             elseif (clickPoint(1)>305&&clickPoint(1)<405)
%                 if(clickPoint(2)>5&&clickPoint(2)<105)
%                     position = [305,5,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>105&&clickPoint(2)<205)
%                     position = [305,105,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>205&&clickPoint(2)<305)
%                     position = [305,205,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>305&&clickPoint(2)<405)
%                     position = [305,305,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 elseif(clickPoint(2)>405&&clickPoint(2)<505)
%                     position = [305,405,position(3),position(4)];
%                     app.a(app.i).Position = position;
%                     app.i =app.i + 1;
%                     app.move=app.move-1;
%                 end
%             end
%             end
%             if app.i == 9
%                 app.diyView.ok.Enable = 'on';
%             end
            
        end

        % Button pushed function: clickButton
        function clickButtonPushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton.Position(1),app.clickButton.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_2
        function clickButton_2Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_2.Position(1),app.clickButton_2.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_3
        function clickButton_3Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_3.Position(1),app.clickButton_3.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_4
        function clickButton_4Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_4.Position(1),app.clickButton_4.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_5
        function clickButton_5Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_5.Position(1),app.clickButton_5.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_6
        function clickButton_6Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_6.Position(1),app.clickButton_6.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_7
        function clickButton_7Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_7.Position(1),app.clickButton_7.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_8
        function clickButton_8Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_8.Position(1),app.clickButton_8.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_9
        function clickButton_9Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_9.Position(1),app.clickButton_9.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_10
        function clickButton_10Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_10.Position(1),app.clickButton_10.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_11
        function clickButton_11Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_11.Position(1),app.clickButton_11.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_12
        function clickButton_12Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_12.Position(1),app.clickButton_12.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_13
        function clickButton_13Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_13.Position(1),app.clickButton_13.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_14
        function clickButton_14Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_14.Position(1),app.clickButton_14.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_15
        function clickButton_15Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_15.Position(1),app.clickButton_15.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end

        % Button pushed function: clickButton_16
        function clickButton_16Pushed(app, event)
            position = app.diyView.ButtonHandle;
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            app.b = [app.clickButton app.clickButton_2 app.clickButton_3 app.clickButton_4 app.clickButton_5 app.clickButton_6 app.clickButton_7 app.clickButton_8 app.clickButton_9 app.clickButton_10 app.clickButton_11 app.clickButton_12 app.clickButton_13 app.clickButton_14 app.clickButton_15 app.clickButton_16];
            app.move = app.move-1;
            app.a(app.i).Icon = app.diyView.IconHandle;
            P = [app.clickButton_16.Position(1),app.clickButton_16.Position(2),position(3),position(4)];
            app.a(app.i).Position = P;
            for x = 1:16
                app.b(x).Position = [800,800,90,90];
            end
            app.i =app.i + 1;
            if(position(3)==90&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
            elseif (position(3)==90&&position(4)==190)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x-4} = 1;
            elseif (position(3)==190&&position(4)==90)
                y = (P(2)-5)/100+1;
                y = 6-y;
                x = (P(1)-5)/100+1;
                app.board{(y-1)*4+x} = 1;
                app.board{(y-1)*4+x+1} = 1;
                app.board
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Caocao = uibutton(app.UIFigure, 'push');
            app.Caocao.Icon = '曹操.jpg';
            app.Caocao.Text = '';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '';
            app.clickButton = uibutton(app.UIFigure, 'push');
            app.clickButton.ButtonPushedFcn = createCallbackFcn(app, @clickButtonPushed, true);
            app.clickButton.Text = 'click';
            app.clickButton_2 = uibutton(app.UIFigure, 'push');
            app.clickButton_2.ButtonPushedFcn = createCallbackFcn(app, @clickButton_2Pushed, true);
            app.clickButton_2.Text = 'click';
            app.clickButton_3 = uibutton(app.UIFigure, 'push');
            app.clickButton_3.ButtonPushedFcn = createCallbackFcn(app, @clickButton_3Pushed, true);
            app.clickButton_3.Text = 'click';
            app.clickButton_4 = uibutton(app.UIFigure, 'push');
            app.clickButton_4.ButtonPushedFcn = createCallbackFcn(app, @clickButton_4Pushed, true);
            app.clickButton_4.Text = 'click';
            app.clickButton_5 = uibutton(app.UIFigure, 'push');
            app.clickButton_5.ButtonPushedFcn = createCallbackFcn(app, @clickButton_5Pushed, true);
            app.clickButton_5.Text = 'click';
            app.clickButton_6 = uibutton(app.UIFigure, 'push');
            app.clickButton_6.ButtonPushedFcn = createCallbackFcn(app, @clickButton_6Pushed, true);
            app.clickButton_6.Text = 'click';
            app.clickButton_7 = uibutton(app.UIFigure, 'push');
            app.clickButton_7.ButtonPushedFcn = createCallbackFcn(app, @clickButton_7Pushed, true);
            app.clickButton_7.Text = 'click';
            app.clickButton_8 = uibutton(app.UIFigure, 'push');
            app.clickButton_8.ButtonPushedFcn = createCallbackFcn(app, @clickButton_8Pushed, true);
            app.clickButton_8.Text = 'click';
            app.clickButton_9 = uibutton(app.UIFigure, 'push');
            app.clickButton_9.ButtonPushedFcn = createCallbackFcn(app, @clickButton_9Pushed, true);
            app.clickButton_9.Text = 'click';
            app.clickButton_10 = uibutton(app.UIFigure, 'push');
            app.clickButton_10.ButtonPushedFcn = createCallbackFcn(app, @clickButton_10Pushed, true);
            app.clickButton_10.Text = 'click';
            app.clickButton_11 = uibutton(app.UIFigure, 'push');
            app.clickButton_11.ButtonPushedFcn = createCallbackFcn(app, @clickButton_11Pushed, true);
            app.clickButton_11.Text = 'click';
            app.clickButton_12 = uibutton(app.UIFigure, 'push');
            app.clickButton_12.ButtonPushedFcn = createCallbackFcn(app, @clickButton_12Pushed, true);
            app.clickButton_12.Text = 'click';
            app.clickButton_13 = uibutton(app.UIFigure, 'push');
            app.clickButton_13.ButtonPushedFcn = createCallbackFcn(app, @clickButton_13Pushed, true);
            app.clickButton_13.Text = 'click';
            app.clickButton_14 = uibutton(app.UIFigure, 'push');
            app.clickButton_14.ButtonPushedFcn = createCallbackFcn(app, @clickButton_14Pushed, true);
            app.clickButton_14.Text = 'click';
            app.clickButton_15 = uibutton(app.UIFigure, 'push');
            app.clickButton_15.ButtonPushedFcn = createCallbackFcn(app, @clickButton_15Pushed, true);
            app.clickButton_15.Text = 'click';
            app.clickButton_16 = uibutton(app.UIFigure, 'push');
            app.clickButton_16.ButtonPushedFcn = createCallbackFcn(app, @clickButton_16Pushed, true);
            app.clickButton_16.Text = 'click';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = creatView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end