classdef diyView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        Button_9  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
        Button_7  matlab.ui.control.Button
        Button_8  matlab.ui.control.Button
        reset     matlab.ui.control.Button
        creat     matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        ok        matlab.ui.control.Button
    end

    
    properties (Access = public)
        viewHandle
        ButtonHandle % 句柄用于确定选择棋子
        IconHandle % 用于确定选定棋子的图标
        emptyClick
        create      % create view
        startDB
        gameView
        chessDB
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.ButtonHandle = app.Button.Position;
            app.IconHandle = app.Button.Icon;
            app.viewHandle.move=1;
            app.Button.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ButtonHandle = app.Button_2.Position;
            app.IconHandle = app.Button_2.Icon;
            app.viewHandle.move=1;
            app.Button_2.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ButtonHandle = app.Button_3.Position;
            app.IconHandle = app.Button_3.Icon;
            app.viewHandle.move=1;
            app.Button_3.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ButtonHandle = app.Button_5.Position;
            app.IconHandle = app.Button_5.Icon;
            app.viewHandle.move=1;
            app.Button_5.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.ButtonHandle = app.Button_9.Position;
            app.IconHandle = app.Button_9.Icon;
            app.viewHandle.move=1;
            app.Button_9.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.ButtonHandle = app.Button_6.Position;
            app.IconHandle = app.Button_6.Icon;
            app.viewHandle.move=1;
            app.Button_6.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.ButtonHandle = app.Button_7.Position;
            app.IconHandle = app.Button_7.Icon;
            app.viewHandle.move=1;
            app.Button_7.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.ButtonHandle = app.Button_8.Position;
            app.IconHandle = app.Button_8.Icon;
            app.viewHandle.move=1;
            app.Button_8.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ButtonHandle = app.Button_4.Position;
            app.IconHandle = app.Button_4.Icon;
            app.viewHandle.move=1;
            app.Button_4.Enable = 'off';
            app.create.func();
        end

        % Button pushed function: reset
        function resetPushed(app, event)
            for i = 1:9
                app.viewHandle.a(i).Position = [-261,258,99,26];
            end
            app.Button.Enable = 'on';
            app.Button_2.Enable = 'on';
            app.Button_3.Enable = 'on';
            app.Button_4.Enable = 'on';
            app.Button_5.Enable = 'on';
            app.Button_6.Enable = 'on';
            app.Button_7.Enable = 'on';
            app.Button_8.Enable = 'on';
            app.Button_9.Enable = 'on';
            app.viewHandle.i = 1;
            app.create.board = {0,1,1,0,0,1,1,0,0,0,0,0,0,0,0,0,0,0,0,0};
        end

        % Button pushed function: creat
        function creatPushed(app, event)
            app.create = creatView;
            app.viewHandle = app.create;
            app.create.diyView = app;
            app.create.i = 1;
        end

        % Button pushed function: ok
        function okPushed(app, event)
            app.startDB.mode = 'diy';
            app.startDB.diyPosition{1} = [105 305 190 190];
            tmp = 7;
            for i = 1:9
                switch app.viewHandle.a(i).Icon
                    case '张飞.jpg'
                        app.startDB.diyPosition{2} = app.viewHandle.a(i).Position;
                    case '赵云.jpg'
                        app.startDB.diyPosition{3} = app.viewHandle.a(i).Position;
                    case '马超.jpg'
                        app.startDB.diyPosition{4} = app.viewHandle.a(i).Position;
                    case '黄忠.jpg'
                        app.startDB.diyPosition{5} = app.viewHandle.a(i).Position;
                    case '关羽.jpg'
                        app.startDB.diyPosition{6} = app.viewHandle.a(i).Position;
                    case '士兵.jpg'
                        app.startDB.diyPosition{tmp} = app.viewHandle.a(i).Position;
                        tmp = tmp +1;
                end
            end
            app.startDB.startPosition=app.startDB.getStartPosition();
            app.gameView.Caocao.Position = app.startDB.startPosition{1};
            app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
            app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
            app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
            app.gameView.Machao.Position = app.startDB.startPosition{4};
            app.gameView.Guanyu.Position = app.startDB.startPosition{6};
            app.gameView.soldier1.Position = app.startDB.startPosition{7};
            app.gameView.soldier2.Position = app.startDB.startPosition{9};
            app.gameView.soldier3.Position = app.startDB.startPosition{10};
            app.gameView.soldier4.Position = app.startDB.startPosition{8};
            app.gameView.StepButton.Text = '步数';
            app.chessDB.step = 0;
            app.chessDB.position = app.startDB.startPosition;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '自定义布局';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '张飞.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = '赵云.jpg';
            app.Button_2.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = '士兵.jpg';
            app.Button_5.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = '士兵.jpg';
            app.Button_9.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = '士兵.jpg';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = '士兵.jpg';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = '关羽.jpg';
            app.Button_8.Text = '';
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetPushed, true);
            app.reset.Text = {'重新摆放'; ''};
            app.creat = uibutton(app.UIFigure, 'push');
            app.creat.ButtonPushedFcn = createCallbackFcn(app, @creatPushed, true);
            app.creat.Text = {'自定义'; ''};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = '黄忠.jpg';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = '马超.jpg';
            app.Button_4.Text = '';
            app.ok = uibutton(app.UIFigure, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @okPushed, true);
            app.ok.Enable = 'off';
            app.ok.Text = '确定';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diyView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end