classdef modeChooseView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        hengdaolima   matlab.ui.control.Button
        qitoubingjin  matlab.ui.control.Button
        bingfensanlu  matlab.ui.control.Button
        weierbujian   matlab.ui.control.Button
        jiezuiandeng  matlab.ui.control.Button
        Button        matlab.ui.control.Button
    end

    
    properties (Access = public)
        startDB
        gameView
        chessDB
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: hengdaolima
        function hengdaolimaButtonPushed(app, event)
           app.startDB.mode='hengdaolima';
           app.startDB.startPosition=app.startDB.getStartPosition();
           app.gameView.Caocao.Position = app.startDB.startPosition{1};
           app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
           app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
           app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
           app.gameView.Machao.Position = app.startDB.startPosition{4};
           app.gameView.Guanyu.Position = app.startDB.startPosition{6};
           app.gameView.soldier1.Position = app.startDB.startPosition{7};
           app.gameView.soldier2.Position = app.startDB.startPosition{9};
           app.gameView.soldier3.Position = app.startDB.startPosition{10};
           app.gameView.soldier4.Position = app.startDB.startPosition{8};
           app.gameView.StepButton.Text = '步数';
           app.chessDB.step = 0;
           app.chessDB.position = app.startDB.startPosition;
        end

        % Button pushed function: qitoubingjin
        function qitoubingjinButtonPushed(app, event)
            app.startDB.mode='qitoubingjin';
            app.startDB.startPosition=app.startDB.getStartPosition();
            app.gameView.Caocao.Position = app.startDB.startPosition{1};
            app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
            app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
            app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
            app.gameView.Machao.Position = app.startDB.startPosition{4};
            app.gameView.Guanyu.Position = app.startDB.startPosition{6};
            app.gameView.soldier1.Position = app.startDB.startPosition{7};
            app.gameView.soldier2.Position = app.startDB.startPosition{9};
            app.gameView.soldier3.Position = app.startDB.startPosition{10};
            app.gameView.soldier4.Position = app.startDB.startPosition{8};
            app.gameView.StepButton.Text = '步数';
            app.chessDB.step = 0;
            app.chessDB.position = app.startDB.startPosition;
        end

        % Button pushed function: bingfensanlu
        function bingfensanluButtonPushed(app, event)
            app.startDB.mode='bingfensanlu';
            app.startDB.startPosition=app.startDB.getStartPosition();
            app.gameView.Caocao.Position = app.startDB.startPosition{1};
            app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
            app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
            app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
            app.gameView.Machao.Position = app.startDB.startPosition{4};
            app.gameView.Guanyu.Position = app.startDB.startPosition{6};
            app.gameView.soldier1.Position = app.startDB.startPosition{7};
            app.gameView.soldier2.Position = app.startDB.startPosition{9};
            app.gameView.soldier3.Position = app.startDB.startPosition{10};
            app.gameView.soldier4.Position = app.startDB.startPosition{8};
            app.gameView.StepButton.Text = '步数';
            app.chessDB.step = 0;
            app.chessDB.position = app.startDB.startPosition;
        end

        % Button pushed function: weierbujian
        function weierbujianButtonPushed(app, event)
            app.startDB.mode='weierbujian';
            app.startDB.startPosition=app.startDB.getStartPosition();
            app.gameView.Caocao.Position = app.startDB.startPosition{1};
            app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
            app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
            app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
            app.gameView.Machao.Position = app.startDB.startPosition{4};
            app.gameView.Guanyu.Position = app.startDB.startPosition{6};
            app.gameView.soldier1.Position = app.startDB.startPosition{7};
            app.gameView.soldier2.Position = app.startDB.startPosition{9};
            app.gameView.soldier3.Position = app.startDB.startPosition{10};
            app.gameView.soldier4.Position = app.startDB.startPosition{8};
            app.gameView.StepButton.Text = '步数';
            app.chessDB.step = 0;
            app.chessDB.position = app.startDB.startPosition;
        end

        % Button pushed function: jiezuiandeng
        function jiezuiandengButtonPushed(app, event)
            app.startDB.mode='jiezuxiandeng';
            app.startDB.startPosition=app.startDB.getStartPosition();
            app.gameView.Caocao.Position = app.startDB.startPosition{1};
            app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
            app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
            app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
            app.gameView.Machao.Position = app.startDB.startPosition{4};
            app.gameView.Guanyu.Position = app.startDB.startPosition{6};
            app.gameView.soldier1.Position = app.startDB.startPosition{7};
            app.gameView.soldier2.Position = app.startDB.startPosition{9};
            app.gameView.soldier3.Position = app.startDB.startPosition{10};
            app.gameView.soldier4.Position = app.startDB.startPosition{8};
            app.gameView.StepButton.Text = '步数';
            app.chessDB.step = 0;
            app.chessDB.position = app.startDB.startPosition;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.startDB.mode='test';
            app.startDB.startPosition=app.startDB.getStartPosition();
            app.gameView.Caocao.Position = app.startDB.startPosition{1};
            app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
            app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
            app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
            app.gameView.Machao.Position = app.startDB.startPosition{4};
            app.gameView.Guanyu.Position = app.startDB.startPosition{6};
            app.gameView.soldier1.Position = app.startDB.startPosition{7};
            app.gameView.soldier2.Position = app.startDB.startPosition{9};
            app.gameView.soldier3.Position = app.startDB.startPosition{10};
            app.gameView.soldier4.Position = app.startDB.startPosition{8};
            app.chessDB.step = 69;
            app.gameView.StepButton.Text=num2str(app.chessDB.step);
            app.chessDB.position = app.startDB.startPosition;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '选择默认开局';
            app.hengdaolima = uibutton(app.UIFigure, 'push');
            app.hengdaolima.ButtonPushedFcn = createCallbackFcn(app, @hengdaolimaButtonPushed, true);
            app.hengdaolima.Text = '横刀立马';
            app.qitoubingjin = uibutton(app.UIFigure, 'push');
            app.qitoubingjin.ButtonPushedFcn = createCallbackFcn(app, @qitoubingjinButtonPushed, true);
            app.qitoubingjin.Text = '齐头并进';
            app.bingfensanlu = uibutton(app.UIFigure, 'push');
            app.bingfensanlu.ButtonPushedFcn = createCallbackFcn(app, @bingfensanluButtonPushed, true);
            app.bingfensanlu.Text = '兵分三路';
            app.weierbujian = uibutton(app.UIFigure, 'push');
            app.weierbujian.ButtonPushedFcn = createCallbackFcn(app, @weierbujianButtonPushed, true);
            app.weierbujian.Text = '围而不歼';
            app.jiezuiandeng = uibutton(app.UIFigure, 'push');
            app.jiezuiandeng.ButtonPushedFcn = createCallbackFcn(app, @jiezuiandengButtonPushed, true);
            app.jiezuiandeng.Text = '捷足先登';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'内测开局'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modeChooseView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end