classdef optionView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        resume    matlab.ui.control.Button
        choose    matlab.ui.control.Button
        diy       matlab.ui.control.Button
        theme     matlab.ui.control.Button
    end

    
    properties (Access = public)
        gameView
        chessDB
        startDB
        mcv     % mode choice
        DIY
        tbp     % theme choice
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resume
        function resumeButtonPushed(app, event)
            app.startDB.startPosition=app.startDB.getStartPosition();
            app.gameView.Caocao.Position = app.startDB.startPosition{1};
            app.gameView.Zhangfei.Position = app.startDB.startPosition{2};
            app.gameView.Zhaoyun.Position = app.startDB.startPosition{3};
            app.gameView.Huangzhong.Position = app.startDB.startPosition{5};
            app.gameView.Machao.Position = app.startDB.startPosition{4};
            app.gameView.Guanyu.Position = app.startDB.startPosition{6};
            app.gameView.soldier1.Position = app.startDB.startPosition{7};
            app.gameView.soldier2.Position = app.startDB.startPosition{9};
            app.gameView.soldier3.Position = app.startDB.startPosition{10};
            app.gameView.soldier4.Position = app.startDB.startPosition{8};
            app.gameView.StepButton.Text = '步数';
            app.chessDB.step = 0;
            app.chessDB.position = app.startDB.startPosition;
        end

        % Button pushed function: choose
        function chooseButtonPushed(app, event)
            app.mcv = modeChooseView();
            app.mcv.startDB = app.startDB;
            app.mcv.gameView = app.gameView;
            app.mcv.chessDB = app.chessDB;
        end

        % Button pushed function: diy
        function diyButtonPushed(app, event)
            app.DIY = diyView();
            app.DIY.startDB = app.startDB;
            app.DIY.gameView = app.gameView;
            app.DIY.chessDB = app.chessDB;
        end

        % Button pushed function: theme
        function themeButtonPushed(app, event)
            app.tbp = themeChooseView();
            app.tbp.gameView = app.gameView;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '选项';
            app.resume = uibutton(app.UIFigure, 'push');
            app.resume.ButtonPushedFcn = createCallbackFcn(app, @resumeButtonPushed, true);
            app.resume.Text = '重新开始';
            app.choose = uibutton(app.UIFigure, 'push');
            app.choose.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.choose.Text = '选择默认开局';
            app.diy = uibutton(app.UIFigure, 'push');
            app.diy.ButtonPushedFcn = createCallbackFcn(app, @diyButtonPushed, true);
            app.diy.Text = '自定义开局';
            app.theme = uibutton(app.UIFigure, 'push');
            app.theme.ButtonPushedFcn = createCallbackFcn(app, @themeButtonPushed, true);
            app.theme.Text = '主题选择';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optionView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end