classdef themeChooseView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        theme1    matlab.ui.control.Button
        theme2    matlab.ui.control.Button
    end

    
    properties (Access = public)
        gameView
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: theme1
        function theme1ButtonPushed(app, event)
            app.gameView.Zhangfei.Icon = "张飞.jpg";
            app.gameView.Caocao.Icon = "曹操.jpg";
            app.gameView.Zhaoyun.Icon = "赵云.jpg";
            app.gameView.Huangzhong.Icon = "黄忠.jpg";
            app.gameView.Machao.Icon = "马超.jpg";
            app.gameView.Guanyu.Icon = "关羽.jpg";
            app.gameView.soldier1.Icon = "士兵.jpg";
            app.gameView.soldier2.Icon = "士兵.jpg";
            app.gameView.soldier3.Icon = "士兵.jpg";
            app.gameView.soldier4.Icon = "士兵.jpg";
        end

        % Button pushed function: theme2
        function theme2ButtonPushed(app, event)
            app.gameView.Zhangfei.Icon = "张飞1.jpg";
            app.gameView.Caocao.Icon = "曹操1.jpg";
            app.gameView.Zhaoyun.Icon = "赵云1.jpg";
            app.gameView.Huangzhong.Icon = "黄忠1.jpg";
            app.gameView.Machao.Icon = "马超1.jpg";
            app.gameView.Guanyu.Icon = "关羽1.jpg";
            app.gameView.soldier1.Icon = "士兵1.jpg";
            app.gameView.soldier2.Icon = "士兵1.jpg";
            app.gameView.soldier3.Icon = "士兵1.jpg";
            app.gameView.soldier4.Icon = "士兵1.jpg";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '主题选择';
            app.theme1 = uibutton(app.UIFigure, 'push');
            app.theme1.ButtonPushedFcn = createCallbackFcn(app, @theme1ButtonPushed, true);
            app.theme1.Text = '主题1';
            app.theme2 = uibutton(app.UIFigure, 'push');
            app.theme2.ButtonPushedFcn = createCallbackFcn(app, @theme2ButtonPushed, true);
            app.theme2.Text = '主题2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = themeChooseView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end